classdef Grating_Utilities < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DiffractionTab                matlab.ui.container.Tab
        HorizontalGratingDensitylmmEditFieldLabel  matlab.ui.control.Label
        HorizontalGratingDensitylmmEditField  matlab.ui.control.NumericEditField
        d_xnmEditFieldLabel           matlab.ui.control.Label
        d_xnmEditField                matlab.ui.control.NumericEditField
        VerticalGratingDensitylmmEditFieldLabel  matlab.ui.control.Label
        VerticalGratingDensitylmmEditField  matlab.ui.control.NumericEditField
        d_ynmEditFieldLabel           matlab.ui.control.Label
        d_ynmEditField                matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel    matlab.ui.control.Label
        WavelengthnmEditField         matlab.ui.control.NumericEditField
        CCDWidthpxEditFieldLabel      matlab.ui.control.Label
        CCDWidthpxEditField           matlab.ui.control.NumericEditField
        CCDHeightpxEditFieldLabel     matlab.ui.control.Label
        CCDHeightpxEditField          matlab.ui.control.NumericEditField
        CCDPixelSizeumEditFieldLabel  matlab.ui.control.Label
        CCDPixelSizeumEditField       matlab.ui.control.NumericEditField
        CCDDistancemmEditFieldLabel   matlab.ui.control.Label
        CCDDistancemmEditField        matlab.ui.control.NumericEditField
        BeamsizeumEditFieldLabel      matlab.ui.control.Label
        BeamsizeumEditField           matlab.ui.control.NumericEditField
        MaxTheta_xdegEditFieldLabel   matlab.ui.control.Label
        MaxTheta_xdegEditField        matlab.ui.control.NumericEditField
        MaxTheta_ydegEditFieldLabel   matlab.ui.control.Label
        MaxTheta_ydegEditField        matlab.ui.control.NumericEditField
        m_xEditFieldLabel             matlab.ui.control.Label
        m_xEditField                  matlab.ui.control.NumericEditField
        Theta_xdegLabel               matlab.ui.control.Label
        Theta_xdegEditField           matlab.ui.control.NumericEditField
        DeltaxpxEditFieldLabel        matlab.ui.control.Label
        DeltaxpxEditField             matlab.ui.control.NumericEditField
        m_yEditFieldLabel             matlab.ui.control.Label
        m_yEditField                  matlab.ui.control.NumericEditField
        Theta_ydegEditFieldLabel      matlab.ui.control.Label
        Theta_ydegEditField           matlab.ui.control.NumericEditField
        DeltaypxEditFieldLabel        matlab.ui.control.Label
        DeltaypxEditField             matlab.ui.control.NumericEditField
        Maxm_xEditFieldLabel          matlab.ui.control.Label
        Maxm_xEditField               matlab.ui.control.NumericEditField
        Maxm_yEditFieldLabel          matlab.ui.control.Label
        Maxm_yEditField               matlab.ui.control.NumericEditField
        CalculateButton               matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        PhotonEnergyJEditFieldLabel   matlab.ui.control.Label
        PhotonEnergyJEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CenterList
    end
    
    methods (Access = private)
        
        function Calculate(app)
            CCDW = app.CCDWidthpxEditField.Value;
            CCDH = app.CCDHeightpxEditField.Value;
            PixelSize = app.CCDPixelSizeumEditField.Value * 1e-6;
            CCDD = app.CCDDistancemmEditField.Value * 1e-3;
            
            d_x = app.d_xnmEditField.Value * 1e-9;
            d_y = app.d_ynmEditField.Value * 1e-9;
            Lambda = app.WavelengthnmEditField.Value * 1e-9;

            Theta_x_max = atan(CCDW*PixelSize/(2*CCDD));
            Theta_y_max = atan(CCDH*PixelSize/(2*CCDD));
            
            app.MaxTheta_xdegEditField.Value = rad2deg(Theta_x_max);
            app.MaxTheta_ydegEditField.Value = rad2deg(Theta_y_max);
            
            [m_x_max, ~, ~, ~] = CalcGratingFormula(0,Lambda,d_x,Theta_x_max);
            [m_y_max, ~, ~, ~] = CalcGratingFormula(0,Lambda,d_y,Theta_y_max);

            app.Maxm_xEditField.Value = floor(m_x_max);
            app.Maxm_yEditField.Value = floor(m_y_max);

            m_x = app.m_xEditField.Value;
            m_y = app.m_yEditField.Value;

            [~, ~, ~, Theta_x] = CalcGratingFormula(m_x, Lambda, d_x, 0);
            [~, ~, ~, Theta_y] = CalcGratingFormula(m_y, Lambda, d_y, 0);

            Delta_x = tan(Theta_x) * CCDD / PixelSize;
            Delta_y = tan(Theta_y) * CCDD / PixelSize;

            app.Theta_xdegEditField.Value = rad2deg(Theta_x);
            app.Theta_ydegEditField.Value = rad2deg(Theta_y);
            app.DeltaxpxEditField.Value = Delta_x;
            app.DeltaypxEditField.Value = Delta_y;

            N_x = ceil(m_x_max) * 2 + 1;
            N_y = ceil(m_y_max) * 2 + 1;

            Delta_x_List = zeros(N_x,1);
            Delta_y_List = zeros(N_y,1);

            for i = 1:N_x
                m = ceil(m_x_max) - i + 1;
                if m == 0
                    Delta_x_List(i) = 0;
                else
                    [~, ~, ~, Theta] = CalcGratingFormula(m, Lambda, d_x, 0);
                    Delta_x_List(i) = tan(Theta) * CCDD;
                end
            end

            for i = 1:N_y
                m = ceil(m_y_max) - i + 1;
                if m == 0
                    Delta_y_List(i) = 0;
                else
                    [~, ~, ~, Theta] = CalcGratingFormula(m, Lambda, d_y, 0);
                    Delta_y_List(i) = tan(Theta) * CCDD;
                end
            end

            app.CenterList = zeros(N_x * N_y, 2);
            for i = 1:N_x
                for j = 1:N_y
                    app.CenterList((i-1)*N_y+j,:) = [Delta_x_List(i), Delta_y_List(j)];
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.Calculate();
        end

        % Value changed function: 
        % HorizontalGratingDensitylmmEditField
        function HorizontalGratingDensitylmmEditFieldValueChanged(app, event)
            value = app.HorizontalGratingDensitylmmEditField.Value;
            app.d_xnmEditField.Value = 1e6/value;
        end

        % Value changed function: d_xnmEditField
        function d_xnmEditFieldValueChanged(app, event)
            value = app.d_xnmEditField.Value;
            app.HorizontalGratingDensitylmmEditField.Value = 1e6/value;
        end

        % Value changed function: VerticalGratingDensitylmmEditField
        function VerticalGratingDensitylmmEditFieldValueChanged(app, event)
            value = app.VerticalGratingDensitylmmEditField.Value;
            app.d_ynmEditField.Value = 1e6/value;
        end

        % Value changed function: d_ynmEditField
        function d_ynmEditFieldValueChanged(app, event)
            value = app.d_ynmEditField.Value;
            app.VerticalGratingDensitylmmEditField.Value = 1e6/value;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            f = figure(1);
            ax = axes('Parent',f);
            BeamListScPlot(ax, app.CenterList, app.CCDWidthpxEditField.Value, app.CCDHeightpxEditField.Value, app.CCDPixelSizeumEditField.Value * 1e-6, app.BeamsizeumEditField.Value*1e-6);
            title(ax, "Image on CCD");
            drawnow;
            f2 = figure(2);
            ax2_1 = subplot(2,1,1);
            BeamListDistPlot(ax2_1, app.CenterList, app.CCDHeightpxEditField.Value / 2, 1, app.CCDWidthpxEditField.Value, app.CCDHeightpxEditField.Value, app.CCDPixelSizeumEditField.Value * 1e-6, app.BeamsizeumEditField.Value*1e-6);
            title(ax2_1, "Intensity Profile at x = 0");
            ax2_2 = subplot(2,1,2);
            BeamListDistPlot(ax2_2, app.CenterList, app.CCDWidthpxEditField.Value / 2, 2, app.CCDWidthpxEditField.Value, app.CCDHeightpxEditField.Value, app.CCDPixelSizeumEditField.Value * 1e-6, app.BeamsizeumEditField.Value*1e-6);
            title(ax2_2, "Intensity Profile at y = 0");
            drawnow;


        end

        % Value changed function: PhotonEnergyJEditField
        function PhotonEnergyJEditFieldValueChanged(app, event)
            value = app.PhotonEnergyJEditField.Value;
            h=6.62607015e-34;
            c=299792458;
            app.WavelengthnmEditField.Value=h.*c./value;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DiffractionTab = uitab(app.TabGroup);
            app.DiffractionTab.Title = 'Diffraction';
            app.HorizontalGratingDensitylmmEditFieldLabel = uilabel(app.DiffractionTab);
            app.HorizontalGratingDensitylmmEditFieldLabel.Text = 'Horizontal Grating Density (l/mm)';
            app.HorizontalGratingDensitylmmEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.HorizontalGratingDensitylmmEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontalGratingDensitylmmEditFieldValueChanged, true);
            app.d_xnmEditFieldLabel = uilabel(app.DiffractionTab);
            app.d_xnmEditFieldLabel.Text = 'd_x (nm)';
            app.d_xnmEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.d_xnmEditField.ValueChangedFcn = createCallbackFcn(app, @d_xnmEditFieldValueChanged, true);
            app.VerticalGratingDensitylmmEditFieldLabel = uilabel(app.DiffractionTab);
            app.VerticalGratingDensitylmmEditFieldLabel.Text = 'Vertical Grating Density (l/mm)';
            app.VerticalGratingDensitylmmEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.VerticalGratingDensitylmmEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalGratingDensitylmmEditFieldValueChanged, true);
            app.d_ynmEditFieldLabel = uilabel(app.DiffractionTab);
            app.d_ynmEditFieldLabel.Text = 'd_y (nm)';
            app.d_ynmEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.d_ynmEditField.ValueChangedFcn = createCallbackFcn(app, @d_ynmEditFieldValueChanged, true);
            app.WavelengthnmEditFieldLabel = uilabel(app.DiffractionTab);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.CCDWidthpxEditFieldLabel = uilabel(app.DiffractionTab);
            app.CCDWidthpxEditFieldLabel.Text = 'CCD Width (px)';
            app.CCDWidthpxEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.CCDHeightpxEditFieldLabel = uilabel(app.DiffractionTab);
            app.CCDHeightpxEditFieldLabel.Text = 'CCD Height (px)';
            app.CCDHeightpxEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.CCDPixelSizeumEditFieldLabel = uilabel(app.DiffractionTab);
            app.CCDPixelSizeumEditFieldLabel.Text = 'CCD Pixel Size (um)';
            app.CCDPixelSizeumEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.CCDDistancemmEditFieldLabel = uilabel(app.DiffractionTab);
            app.CCDDistancemmEditFieldLabel.Text = 'CCD Distance (mm)';
            app.CCDDistancemmEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.BeamsizeumEditFieldLabel = uilabel(app.DiffractionTab);
            app.BeamsizeumEditFieldLabel.Text = 'Beam size (um)';
            app.BeamsizeumEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.MaxTheta_xdegEditFieldLabel = uilabel(app.DiffractionTab);
            app.MaxTheta_xdegEditFieldLabel.Text = 'Max Theta_x (deg)';
            app.MaxTheta_xdegEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.MaxTheta_ydegEditFieldLabel = uilabel(app.DiffractionTab);
            app.MaxTheta_ydegEditFieldLabel.Text = 'Max Theta_y (deg)';
            app.MaxTheta_ydegEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.m_xEditFieldLabel = uilabel(app.DiffractionTab);
            app.m_xEditFieldLabel.Text = 'm_x';
            app.m_xEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.m_xEditField.RoundFractionalValues = 'on';
            app.m_xEditField.Value = 1;
            app.Theta_xdegLabel = uilabel(app.DiffractionTab);
            app.Theta_xdegLabel.Text = 'Theta_x (deg)';
            app.Theta_xdegEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.DeltaxpxEditFieldLabel = uilabel(app.DiffractionTab);
            app.DeltaxpxEditFieldLabel.Text = 'Delta x (px)';
            app.DeltaxpxEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.m_yEditFieldLabel = uilabel(app.DiffractionTab);
            app.m_yEditFieldLabel.Text = 'm_y';
            app.m_yEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.m_yEditField.RoundFractionalValues = 'on';
            app.m_yEditField.Value = 1;
            app.Theta_ydegEditFieldLabel = uilabel(app.DiffractionTab);
            app.Theta_ydegEditFieldLabel.Text = 'Theta_y (deg)';
            app.Theta_ydegEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.DeltaypxEditFieldLabel = uilabel(app.DiffractionTab);
            app.DeltaypxEditFieldLabel.Text = 'Delta y (px)';
            app.DeltaypxEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.Maxm_xEditFieldLabel = uilabel(app.DiffractionTab);
            app.Maxm_xEditFieldLabel.Text = 'Max m_x';
            app.Maxm_xEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.Maxm_xEditField.RoundFractionalValues = 'on';
            app.Maxm_yEditFieldLabel = uilabel(app.DiffractionTab);
            app.Maxm_yEditFieldLabel.Text = 'Max m_y';
            app.Maxm_yEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.Maxm_yEditField.RoundFractionalValues = 'on';
            app.CalculateButton = uibutton(app.DiffractionTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.PlotButton = uibutton(app.DiffractionTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PhotonEnergyJEditFieldLabel = uilabel(app.DiffractionTab);
            app.PhotonEnergyJEditFieldLabel.Text = 'Photon Energy (J)';
            app.PhotonEnergyJEditField = uieditfield(app.DiffractionTab, 'numeric');
            app.PhotonEnergyJEditField.ValueChangedFcn = createCallbackFcn(app, @PhotonEnergyJEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Grating_Utilities

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end